classdef acousticConditionChecker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        tCount                       matlab.ui.control.Label
        Voicememo5sButton            matlab.ui.control.Button
        LanguageDropDown             matlab.ui.control.DropDown
        LanguageDropDownLabel        matlab.ui.control.Label
        PATHEditField                matlab.ui.control.EditField
        PATHEditFieldLabel           matlab.ui.control.Label
        SETDIRECTORYButton           matlab.ui.control.Button
        ONLamp                       matlab.ui.control.Lamp
        ONLampLabel                  matlab.ui.control.Label
        CounterEditField             matlab.ui.control.NumericEditField
        CounterEditFieldLabel        matlab.ui.control.Label
        CalibrationdBEditField       matlab.ui.control.NumericEditField
        CalibrationdBEditFieldLabel  matlab.ui.control.Label
        ControlpanelPanel            matlab.ui.container.Panel
        MultiattributepanelPanel     matlab.ui.container.Panel
        SAVEButton_2                 matlab.ui.control.Button
        ABORTButton                  matlab.ui.control.Button
        STARTButton_2                matlab.ui.control.Button
        MULTIATTRIBUTEMEASUREMENTButton  matlab.ui.control.Button
        RealtimepanelPanel           matlab.ui.container.Panel
        NChDropDown                  matlab.ui.control.DropDown
        NChDropDownLabel             matlab.ui.control.Label
        SELECTFILEButton             matlab.ui.control.Button
        FILENAMEEditField            matlab.ui.control.EditField
        FILENAMEEditFieldLabel       matlab.ui.control.Label
        SAVEButton                   matlab.ui.control.Button
        STOPButton_2                 matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        TestsignalDropDown           matlab.ui.control.DropDown
        TestsignalDropDownLabel      matlab.ui.control.Label
        REALTIMEMEASUREMENTButton    matlab.ui.control.Button
        SPLDropDown                  matlab.ui.control.DropDown
        SPLDropDownLabel             matlab.ui.control.Label
        CALIBRATIONButton            matlab.ui.control.Button
        INPUTLEVELOKButton           matlab.ui.control.Button
        MessageTextArea              matlab.ui.control.TextArea
        MessageTextAreaLabel         matlab.ui.control.Label
        AcousticenvironmentcheckerLabel  matlab.ui.control.Label
        DIrSpecAxis                  matlab.ui.control.UIAxes
        pRespAxis                    matlab.ui.control.UIAxes
        SpecAxis                     matlab.ui.control.UIAxes
        levelIndicator               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CommonData % Common shared data
    end

    methods (Access = private)

        function results = initializeGraphics(app)
            app.CommonData.giantFFTs = "off";
            app.CalibrationdBEditFieldLabel.Visible = "off";
            fs = 44100;
            app.CommonData.samplingFrequency = fs;
            app.CommonData.recorder = audiorecorder(fs,24,1);
            beeps = hanning(fs).*sin(2*pi*(1:fs)'/fs*220).*exp(-(1:fs)'/fs*10);
            app.CommonData.player = audioplayer(beeps,fs);
            playblocking(app.CommonData.player);
            %app.CommonData.baseStr = load("testSigBase400ms.mat");
            %---- spectrum axix
            logFreqAx = 20*2 .^(0:1/48:log2(20000/20))';
            bpfRespF = 20*log10(exp(-(logFreqAx-1000).^2/9.4^5));
            app.CommonData.logFrequencyAxis = logFreqAx(:);
            app.CommonData.ltiHandle = ...
                semilogx(app.SpecAxis,logFreqAx,logFreqAx*0+NaN,"LineWidth",2);
            hold(app.SpecAxis,"on")
            app.CommonData.rtvHandle = ...
                semilogx(app.SpecAxis,logFreqAx,logFreqAx*0-NaN,"LineWidth",2);
            app.CommonData.indrectHandle = ...
                semilogx(app.SpecAxis,logFreqAx,logFreqAx*0-NaN,"LineWidth",2);
            app.CommonData.bpfHandle = ...
                semilogx(app.SpecAxis,logFreqAx,bpfRespF*0-NaN,"LineWidth",2, ...
                "Color",[0 1 0]);
            app.CommonData.bpfRespF = bpfRespF;
            drawnow;
            hold(app.SpecAxis,"off")
            app.SpecAxis.Visible = "off";
            %---- power response axis
            txMs = (0:1000)'/1000;
            app.CommonData.pwHandle = ...
                plot(app.pRespAxis,txMs,txMs*0-NaN,"LineWidth",2);
            hold(app.pRespAxis,"on")
            app.CommonData.pwHandle1k = ...
                plot(app.pRespAxis,txMs,txMs*0-NaN,"LineWidth",2);
            hold(app.pRespAxis,"off")
            app.CommonData.txMs = txMs;
            app.pRespAxis.Visible = "off";
            app.CommonData.diRefHandle = ...
                semilogx(app.DIrSpecAxis,logFreqAx,logFreqAx*0+NaN,"LineWidth",2);
            hold(app.DIrSpecAxis,"on")
            app.CommonData.diRTVHandle = ...
                semilogx(app.DIrSpecAxis,logFreqAx,logFreqAx*0+NaN,"LineWidth",2);
            app.CommonData.diIndHandle = ...
                semilogx(app.DIrSpecAxis,logFreqAx,logFreqAx*0+NaN,"LineWidth",2, ...
                "Color",[0.4940 0.1840 0.5560]);
            hold(app.DIrSpecAxis,"off")
            app.DIrSpecAxis.Visible = "off";
            results = 1;
        end

        function results = inputGainSet(app)
            app.CommonData.messageSubj = {'マイクロフォンの感度を設定します', ...
                'マイクロフォンから30cm離れて出来るだけ大きな声で', ...
                '「あ〜」と言ってください', ...
                'そのときに右側のレベルメーターを見てください', ...
                '棒グラフの値が-10dBを超えないように', ...
                '赤い横線の値が-3dBを超えないように感度を調整して下さい'};
            app.CommonData.messageSubE = {'Input sensitivity adjustment', ...
                'Please shout loudly a sustaned vowel', ...
                'for example, ah...', ...
                'Please adjust the input sensitivity to make', ...
                'the left bar-graph not to exceed -10dB', ...
                'and the red line not to exceed -3dB'};
            dispMessage(app);
            inputLevelMonitorLoop(app);
            results = 1;
        end

        function results = dispMessage(app)
            value = app.LanguageDropDown.Value;
            switch value
                case app.LanguageDropDown.Items{1}
                    app.MessageTextArea.Value = ...
                        app.CommonData.messageSubj;
                case app.LanguageDropDown.Items{2}
                    app.MessageTextArea.Value = ...
                        app.CommonData.messageSubE;
                case app.LanguageDropDown.Items{3}
                    app.MessageTextArea.Value = ...
                        app.CommonData.messageSubj;
            end
            drawnow;
            results = 1;
        end

        function results = inputLevelMonitorLoop(app)
            fs = app.CommonData.samplingFrequency;
            %--- level indicator
            app.CommonData.barHandle = ...
                bar(app.levelIndicator,[-0.5 0.5],[-15 -100], "BaseValue",-100);
            axis(app.levelIndicator,[-1 1 -100 0]);
            hold(app.levelIndicator,"on");
            app.CommonData.mesPeakHandle = ...
                plot(app.levelIndicator,[-1 0], [-10 -10],"LineWidth",2,"Color",[1 0 0]);
            app.CommonData.refPeakHandle = ...
                plot(app.levelIndicator,[0 1], [-100 -100],"LineWidth",2,"Color",[1 0 0]);
            set(app.levelIndicator,"Xticklabel"," ");
            app.Voicememo5sButton.Enable = "off";
            hold(app.levelIndicator,"off");
            counter = 20;
            record(app.CommonData.recorder);
            app.CommonData.running = 1; % 1 for running
            pause(0.5);
            while app.CommonData.running == 1
                pause(0.05);
                y = getaudiodata(app.CommonData.recorder);
                if length(y)>0.5*fs
                    endMargin = round(0.3*fs);
                    currentLvl = 20*log10(std(y(end-endMargin:end)));
                    currentPeak = 20*log10(max(abs(y(end-endMargin:end))));
                    set(app.CommonData.barHandle,"YData",[currentLvl -100]);
                    set(app.CommonData.mesPeakHandle,"YData",[currentPeak currentPeak]);
                    drawnow;
                end
                counter = counter - 0.1;
                if counter <= 0
                    counter = 20;
                    stop(app.CommonData.recorder);
                    pause(0.2);
                    record(app.CommonData.recorder);
                    pause(0.3);
                end
            end
            stop(app.CommonData.recorder);
            app.Voicememo5sButton.Enable = "on";
            results = 1;
        end

        function results = inputLevelCalibration(app)
            app.CommonData.messageSubj = {...
                '『CALIBRATION』ボタンを押してください。音が出ます',...
                'マイクロフォンの位置でレベル（A特性SLOW）を計ります',...
                '騒音計で読み取った値に近いSPLの数字を選んで下さい',...
                '--------------',...
                '二本の棒グラフの左の棒はマイクロフォンの入力',...
                '右側の棒はオーディオインタフェースの出力を示しています',...
                '騒音計で読み取り易いように出力を調整して下さい',...
                '赤い線は最大値です。-3dBを超えないようにして下さい'};
            app.CommonData.messageSubE = {...
                'Click [CALIBRATION] button, then sound starts',...
                'Read the sound pressure level near mic',...
                'Select the closest number of SPL dropdown',...
                '--------------',...
                'The left bar shows the input level',...
                'The right bar shows the output level',...
                'Please adjust output level of ',...
                'The red line shows the peak level'};
            dispMessage(app);
            %inputLevelCalibrationLoop(app);
            results = 1;
        end

        function results = inputLevelCalibrationLoop(app)
            fs = app.CommonData.samplingFrequency;
            oneOctSignal = zeros(fs,1);
            tt = (1:fs)'/fs;
            for ii = round(1000/sqrt(2)):round(1000*sqrt(2))
                oneOctSignal = oneOctSignal+sin(2*pi*ii*tt+2*pi*rand(1,1));
            end
            oneOctSignal = oneOctSignal*0.5/max(abs(oneOctSignal));
            signalDuration = 120; % second
            calibSignal = zeros(signalDuration*fs,1);
            for ii = 1:signalDuration
                calibSignal((ii-1)*fs+(1:fs)) = oneOctSignal;
            end
            %release(app.CommonData.recorder);
            %pause(0.5);
            app.CommonData.channelN = 2;
            try
                app.CommonData.recorder = audiorecorder(fs,24,2);
                record(app.CommonData.recorder);
            catch ME
                disp(ME.identifier);
                pause(0.2);
                app.CommonData.channelN = 1;
                app.CommonData.recorder = audiorecorder(fs,24,1);
                pause(0.2);
                record(app.CommonData.recorder);
            end
            %app.CommonData.recorder =
            doubleCalibSignal = [calibSignal;calibSignal];
            app.CommonData.player = audioplayer(calibSignal,fs);
            app.CommonData.running = 1; % 1 for running
            play(app.CommonData.player);
            record(app.CommonData.recorder);
            app.Voicememo5sButton.Enable = "off";
            pause(0.5);
            while app.CommonData.running == 1
                pause(0.05);
                y = getaudiodata(app.CommonData.recorder);
                if length(y)>1.2*fs
                    endMargin = round(1*fs);
                    if app.CommonData.channelN == 1
                        yy = [y doubleCalibSignal(1:length(y))];
                    else
                        yy = y;
                    end
                    currentLvl = 20*log10(std(yy(end-endMargin:end,:)));
                    currentPeak = 20*log10(max(abs(yy(end-endMargin:end,:))));
                    set(app.CommonData.barHandle,"YData",currentLvl);
                    set(app.CommonData.mesPeakHandle,"YData",[currentPeak(1) currentPeak(1)]);
                    set(app.CommonData.refPeakHandle,"YData",[currentPeak(2) currentPeak(2)]);
                    drawnow;
                end
                if ~isplaying(app.CommonData.player)
                    pause(0.3);
                    play(app.CommonData.player);
                end
            end
            stop(app.CommonData.player);
            app.Voicememo5sButton.Enable = "on";
            results = 1;
        end

        function results = generateTestSignal(app)
            signalType = app.TestsignalDropDown.Value;
            %app.TestsignalDropDown.Items
            switch signalType
                case app.TestsignalDropDown.Items{2}
                    generateCAPRICEPsequence(app);
                case app.TestsignalDropDown.Items{3}
                    generateBakuonSG(app);
                case app.TestsignalDropDown.Items{4}
                    generateLLNPinkNoise(app);
                case app.TestsignalDropDown.Items{5}
                    generateLogSweptSine(app);
                case app.TestsignalDropDown.Items{6}
                    generateMLSsignal(app);
                case app.TestsignalDropDown.Items{7}
                    generateFromFileInput(app);
            end
            app.STARTButton.Enable = "on";
            app.CommonData.messageSubj = {...
                '試験信号が出来ました',...
                '『START』を押せばチェックが始まります', ...
                '『STOP』を押せば終わります'};
            app.CommonData.messageSubE = {...
                'The test signal is ready',...
                'Click [START] button starts measurement', ...
                'Click [STOP] button stops measurement'};
            dispMessage(app);
            app.TestsignalDropDown.Enable = "off";
            results = 1;
        end

        function results = realtimeMeasurementLoop(app)
            fs = app.CommonData.samplingFrequency;
            testSignal = app.CommonData.testSignal;
            app.CommonData.player = ...
                audioplayer(testSignal*0.5/max(abs(testSignal)),fs);
            app.CommonData.channelN = str2double(app.NChDropDown.Value);
            doubleCalibSignal = [testSignal; testSignal];
            try
                app.CommonData.recorder = audiorecorder(fs,24,2);
                record(app.CommonData.recorder);
            catch ME
                disp(ME.identifier);
                pause(0.2);
                app.CommonData.channelN = 1;
                app.CommonData.recorder = audiorecorder(fs,24,1);
                pause(0.2);
                record(app.CommonData.recorder);
            end
            periodLength = app.CommonData.periodLength;
            %inputBuffer = zeros(periodLength*3,2);
            marginL = periodLength*2.5;
            fx = (0:periodLength-1)'/periodLength*fs;
            tx = (1:periodLength)'/fs;
            txMs = app.CommonData.txMs;
            logFreqAx = app.CommonData.logFrequencyAxis;
            app.CommonData.running = 1; % 1 for running
            play(app.CommonData.player);
            app.Voicememo5sButton.Enable = "off";
            counter = 30;
            %record(app.CommonData.recorder);
            app.CommonData.running = 1; % 1 for running
            pause(0.2);% HK 12/09/2025
            app.CounterEditField.Value = counter;
            fxBi = fx;
            fxBi(fx>fs/2) = fxBi(fx>fs/2)-fs;
            selvec = double(abs(fxBi)>100);
            selvec2 = double(abs(fxBi)>40);
            set(app.CommonData.diRefHandle,"YData",logFreqAx*0);
            halfPeriod = round(periodLength/2);
            hBpf = exp(-(abs(fxBi)/271.8).^2);
            %hBpfbi = (circshift(hBpf,1000)+circshift(hBpf,-1000))/2;
            hBpfbi = circshift(hBpf,round(1000/fx(2)));
            switch app.TestsignalDropDown.Value
                case app.TestsignalDropDown.Items{2}
                    tgt1 = zeros(periodLength,1);
                    tgt1(1:periodLength/4:end) = [1 1 1 1]';
                    tgt1 = fft(tgt1);
                    tgt2 = zeros(periodLength,1);
                    tgt2(1:periodLength/4:end) = [1 -1 1 -1]';
                    tgt2 = fft(tgt2);
                    tgt3 = zeros(periodLength,1);
                    tgt3(1:periodLength/4:end) = [1 1 -1 -1]';
                    tgt3 = fft(tgt3);
                otherwise
                    set(app.CommonData.indrectHandle,"YData",logFreqAx*NaN);
            end
            bpfRespF = app.CommonData.bpfRespF;
            set(app.CommonData.bpfHandle,"YData",bpfRespF);
            while app.CommonData.running == 1
                pause(0.05);
                y = getaudiodata(app.CommonData.recorder);
                if length(y)>1.2*periodLength
                    endMargin = round(1*periodLength);
                    %currentLvl = 20*log10(std(y(end-endMargin:end,1)));
                    %set(app.CommonData.barHandle,"YData",currentLvl);
                    if app.CommonData.channelN == 1
                        yy = [y(:,1) doubleCalibSignal(1:length(y))];
                    else
                        yy = y;
                    end
                    currentLvl = 20*log10(std(yy(end-endMargin:end,:)));
                    currentPeak = 20*log10(max(abs(yy(end-endMargin:end,:))));
                    set(app.CommonData.barHandle,"YData",currentLvl);
                    set(app.CommonData.mesPeakHandle,"YData",[currentPeak(1) currentPeak(1)]);
                    set(app.CommonData.refPeakHandle,"YData",[currentPeak(2) currentPeak(2)]);
                    drawnow;
                end
                if length(y) > marginL
                    app.ONLamp.Visible = "on";
                    if app.CommonData.channelN == 2
                        xRefW = y(end-periodLength*2-100:end-100,2);
                        xMesW = y(end-periodLength*2-100:end-100,1);
                    else
                        tmpLoc = length(y)-periodLength*2-100;
                        xRefW = testSignal(tmpLoc+(1:2*periodLength));
                        xMesW = y(end-periodLength*2-100:end-100,1);
                    end
                    xMesWSpec = fft(xRefW(end+1-periodLength:end));
                    hLTI = fft(xMesW(end+1-periodLength:end))./xMesWSpec;
                    xMesWF = xMesW(end+1-periodLength:end);
                    xMesWB = xMesW((end+1-periodLength:end)-periodLength);
                    hRTV = fft(xMesWF-xMesWB)./xMesWSpec;
                    hLTIpw = logSmoothePower(fx,logFreqAx,hLTI);
                    hRTVpw = logSmoothePower(fx,logFreqAx,hRTV);
                    midLevel = mean(hLTIpw(logFreqAx>100 & logFreqAx<10000));
                    switch app.CommonData.giantFFTs
                        case "on"
                        otherwise
                            set(app.CommonData.ltiHandle,"YData",10*log10(hLTIpw/midLevel));
                            set(app.CommonData.rtvHandle,"YData",10*log10(hRTVpw/2/midLevel));
                    end
                    iResp = real(ifft(hLTI.*selvec));
                    iResp1k = real(ifft(hLTI.*hBpfbi));
                    switch app.TestsignalDropDown.Value
                        case app.TestsignalDropDown.Items{2}
                            iResp1 = real(ifft(hLTI.*selvec2.*tgt1));
                            iResp2 = real(ifft(hLTI.*selvec2.*tgt2));
                            iResp3 = real(ifft(hLTI.*selvec2.*tgt3));
                            avIresp = (iResp1+iResp2+iResp3)/3;
                            devResp1 = iResp1-avIresp;
                            devResp2 = iResp2-avIresp;
                            devResp3 = iResp3-avIresp;
                            devResp1F = fft(devResp1(1:periodLength/4),periodLength);
                            devResp2F = fft(devResp2(1:periodLength/4),periodLength);
                            devResp3F = fft(devResp3(1:periodLength/4),periodLength);
                            devRespTmp = (abs(devResp1F).^2+abs(devResp2F).^2+2*abs(devResp3F).^2)/4;
                            devRespPwr = logSmoothePower(fx,logFreqAx,sqrt(devRespTmp));
                            set(app.CommonData.indrectHandle,"YData",10*log10(devRespPwr/midLevel));
                    end
                    pwResp = abs(iResp).^2;
                    cumResp = cumsum(pwResp);
                    pwRespMs = interp1(tx,cumResp,txMs+0.001,"linear","extrap") ...
                        - interp1(tx,cumResp,txMs,"linear","extrap");
                    pwResp1k = abs(iResp1k).^2;
                    cumResp1k = cumsum(pwResp1k);
                    pwRespMs1k = interp1(tx,cumResp1k,txMs+0.001,"linear","extrap") ...
                        - interp1(tx,cumResp1k,txMs,"linear","extrap");
                    selvec3 = double(abs(fxBi)>20);
                    iResp3 = real(ifft(hLTI.*selvec3));
                    if app.CommonData.channelN == 1
                        [~, idxMx] = max(pwRespMs);
                        shiftSmpl = -round(fs*idxMx/1000);
                        iResp = circshift(iResp,shiftSmpl+44);
                        iResp3 = circshift(iResp3,shiftSmpl+44);
                    end
                    set(app.CommonData.pwHandle,"YData",10*log10(pwRespMs/max(pwRespMs)));
                    set(app.CommonData.pwHandle1k,"YData",10*log10(pwRespMs1k/max(pwRespMs1k)));
                    %set(app.CommonData.)
                    refSpec = fft(iResp(1:halfPeriod),periodLength);
                    refSpec3 = fft(iResp3(1:halfPeriod),periodLength);
                    indSpec = fft(iResp((1:halfPeriod)+round(0.015*fs)),periodLength);
                    randSpec = ...
                        fft(iResp((1:halfPeriod)+halfPeriod-round(0.015*fs)),periodLength);
                    randSpec3 = ...
                        fft(iResp3((1:halfPeriod)+halfPeriod-round(0.015*fs)),periodLength);
                    refPw = logSmoothePower(fx,logFreqAx,refSpec);
                    refPw3 = logSmoothePower(fx,logFreqAx,refSpec3);
                    indPw = logSmoothePower(fx,logFreqAx,indSpec);
                    rtvPw = logSmoothePower(fx,logFreqAx,randSpec);
                    rtvPw3 = logSmoothePower(fx,logFreqAx,randSpec3);
                    set(app.CommonData.diIndHandle,"YData",10*log10(indPw./refPw));
                    set(app.CommonData.diRTVHandle,"YData",10*log10(rtvPw./refPw));
                    switch app.CommonData.giantFFTs
                        case "on"
                            midLevel = mean(refPw(logFreqAx>100 & logFreqAx<10000));
                            set(app.CommonData.ltiHandle,"YData",10*log10(refPw3/midLevel));
                            set(app.CommonData.rtvHandle,"YData",10*log10(rtvPw3/midLevel));
                    end
                    drawnow;
                else
                    app.ONLamp.Visible = "off";
                    drawnow
                end
                counter = counter - 0.1;
                app.CounterEditField.Value = counter;
                if counter <= 0
                    app.ONLamp.Visible = "off";
                    counter = 30;
                    stop(app.CommonData.recorder);
                    pause(0.2);
                    record(app.CommonData.recorder);
                    pause(0.3);
                end
            end
            app.CommonData.acquiredSignal = getaudiodata(app.CommonData.recorder);
            stop(app.CommonData.recorder);
            stop(app.CommonData.player);
            app.ONLamp.Visible = "off";
            app.Voicememo5sButton.Enable = "on";
            results = 1;
        end

        function results = generateCAPRICEPsequence(app)
            %fs = app.CommonData.samplingFrequency;
            %output = periodicCAPRICEP4(fs,1,120,"Erf");
            [xTest,fs] = audioread("compactTestSig41k.wav");
            xUnit = xTest(1:fs);%output.pinkSIG;
            testSignal = zeros(fs*120,1);
            for ii = 1:120
                testSignal((ii-1)*fs+(1:fs)) = xUnit;
            end
            app.CommonData.testSignal = 0.8*testSignal/max(abs(testSignal));
            app.CommonData.periodLength = fs;
            app.SpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.DIrSpecAxis.Visible = "on";
            results = 1;
        end

        function results = generateBakuonSG(app)
            [xOrg,fs] = audioread("bakHK5dB.wav");
            nRepeat = ceil(120*fs/length(xOrg));
            x = zeros(nRepeat*length(xOrg),1);
            for ii = 1:nRepeat
                x((ii-1)*length(xOrg)+(1:length(xOrg)),1) = xOrg;
            end
            app.CommonData.testSignal = x;
            app.CommonData.periodLength = length(xOrg);
            app.SpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.DIrSpecAxis.Visible = "on";
            results = 1;
        end

        function results = multiAttributeMeasurement(app)
            [testSignal,fs] = audioread("compactTestSig41k.wav");
            app.CommonData.testSignal = testSignal;
            app.CommonData.player = audioplayer(testSignal,fs);
            app.CommonData.channelN = ...
                str2double(app.NChDropDown.Value);
            doubleCalibSignal = [testSignal; testSignal];
            try
                app.CommonData.recorder = audiorecorder(fs,24,2);
                record(app.CommonData.recorder);
            catch ME
                disp(ME.identifier);
                pause(0.2);
                app.CommonData.channelN = 1;
                app.CommonData.recorder = audiorecorder(fs,24,1);
                pause(0.2);
                record(app.CommonData.recorder);
            end
            periodLength = fs;
            %inputBuffer = zeros(periodLength*3,2);
            marginL = periodLength*2.5;
            fx = (0:periodLength-1)'/periodLength*fs;
            tx = (1:periodLength)'/fs;
            txMs = app.CommonData.txMs;
            logFreqAx = app.CommonData.logFrequencyAxis;
            app.CommonData.running = 1; % 1 for running
            pause(0.5)
            play(app.CommonData.player);
            app.Voicememo5sButton.Enable = "off";
            counter = 30;
            %record(app.CommonData.recorder);
            app.CommonData.running = 1; % 1 for running
            pause(0.5);
            app.CounterEditField.Value = counter;
            fxBi = fx;
            fxBi(fx>fs/2) = fxBi(fx>fs/2)-fs;
            selvec = double(abs(fxBi)>100);
            set(app.CommonData.diRefHandle,"YData",logFreqAx*0);
            halfPeriod = round(periodLength/2);
            app.CommonData.aborted = 0;
            while app.CommonData.running == 1 && ...
                    isplaying(app.CommonData.player)
                pause(0.05);
                y = getaudiodata(app.CommonData.recorder);
                if length(y)>1.2*periodLength
                    endMargin = round(1*periodLength);
                    %currentLvl = 20*log10(std(y(end-endMargin:end,1)));
                    %set(app.CommonData.barHandle,"YData",currentLvl);
                    if app.CommonData.channelN == 1
                        yy = [y(:,1) doubleCalibSignal(1:length(y))];
                    else
                        yy = y;
                    end
                    currentLvl = 20*log10(std(yy(end-endMargin:end,:)));
                    currentPeak = 20*log10(max(abs(yy(end-endMargin:end,:))));
                    set(app.CommonData.barHandle,"YData",currentLvl);
                    set(app.CommonData.mesPeakHandle,"YData",[currentPeak(1) currentPeak(1)]);
                    set(app.CommonData.refPeakHandle,"YData",[currentPeak(2) currentPeak(2)]);
                    drawnow;
                end
                if length(y) > marginL && length(y) < length(testSignal)
                    app.ONLamp.Visible = "on";
                    if app.CommonData.channelN == 2
                        xRefW = y(end-periodLength*2-100:end-100,2);
                        xMesW = y(end-periodLength*2-100:end-100,1);
                    else
                        tmpLoc = length(y)-periodLength*2-100;
                        xRefW = testSignal(tmpLoc+(1:2*periodLength));
                        xMesW = y(end-periodLength*2-100:end-100,1);
                    end
                    xMesWSpec = fft(xRefW(end+1-periodLength:end));
                    hLTI = fft(xMesW(end+1-periodLength:end))./xMesWSpec;
                    xMesWF = xMesW(end+1-periodLength:end);
                    xMesWB = xMesW((end+1-periodLength:end)-periodLength);
                    hRTV = fft(xMesWF-xMesWB)./xMesWSpec;
                    hLTIpw = logSmoothePower(fx,logFreqAx,hLTI);
                    hRTVpw = logSmoothePower(fx,logFreqAx,hRTV);
                    midLevel = mean(hLTIpw(logFreqAx>100 & logFreqAx<10000));
                    set(app.CommonData.ltiHandle,"YData",10*log10(hLTIpw/midLevel));
                    set(app.CommonData.rtvHandle,"YData",10*log10(hRTVpw/2/midLevel));
                    iResp = real(ifft(hLTI.*selvec));
                    pwResp = abs(iResp).^2;
                    cumResp = cumsum(pwResp);
                    pwRespMs = interp1(tx,cumResp,txMs+0.001,"linear","extrap") ...
                        - interp1(tx,cumResp,txMs,"linear","extrap");
                    if app.CommonData.channelN == 1
                        [~, idxMx] = max(pwRespMs);
                        shiftSmpl = -round(fs*idxMx/1000);
                        iResp = circshift(iResp,shiftSmpl+44);
                    end
                    set(app.CommonData.pwHandle,"YData",10*log10(pwRespMs/max(pwRespMs)));
                    %set(app.CommonData.)
                    refSpec = fft(iResp(1:halfPeriod),periodLength);
                    indSpec = fft(iResp((1:halfPeriod)+round(0.015*fs)),periodLength);
                    randSpec = ...
                        fft(iResp((1:halfPeriod)+halfPeriod-round(0.015*fs)),periodLength);
                    refPw = logSmoothePower(fx,logFreqAx,refSpec);
                    indPw = logSmoothePower(fx,logFreqAx,indSpec);
                    rtvPw = logSmoothePower(fx,logFreqAx,randSpec);
                    set(app.CommonData.diIndHandle,"YData",10*log10(indPw./refPw));
                    set(app.CommonData.diRTVHandle,"YData",10*log10(rtvPw./refPw));
                    drawnow;
                else
                    app.ONLamp.Visible = "off";
                    drawnow
                end
            end
            stop(app.CommonData.recorder);
            app.Voicememo5sButton.Enable = "on";
            if app.CommonData.running == 0
                stop(app.CommonData.player);
            end
            app.ONLamp.Visible = "off";
            app.MULTIATTRIBUTEMEASUREMENTButton.Enable = "on";
            if app.CommonData.aborted == 0
                app.SAVEButton_2.Enable = "on";
                app.ABORTButton.Enable = "off";
                app.CommonData.acquiredSignal = y;
            end
            results = 1;
        end

        function results = generateMLSsignal(app)
            %fs = app.CommonData.samplingFrequency;
            periodLength = 2^16-1;
            xUnit = mls(periodLength);
            testSignal = zeros(periodLength*120,1);
            for ii = 1:120
                testSignal((ii-1)*periodLength+(1:periodLength)) = xUnit;
            end
            app.CommonData.testSignal = 0.3*testSignal/max(abs(testSignal));
            app.CommonData.periodLength = periodLength;
            app.SpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.DIrSpecAxis.Visible = "on";
            results = 1;
        end

        function results = generateLogSweptSine(app)
            %periodLength = 2^16-1;
            xUnit = sweeptone(1,0.1);
            periodLength = length(xUnit);
            testSignal = zeros(periodLength*120,1);
            for ii = 1:120
                testSignal((ii-1)*periodLength+(1:periodLength)) = xUnit;
            end
            app.CommonData.testSignal = 0.3*testSignal/max(abs(testSignal));
            app.CommonData.periodLength = periodLength;
            app.SpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.DIrSpecAxis.Visible = "on";
            results = 1;
        end

        function results = generateFromFileInput(app)
            [file,path] = uigetfile({'*.wav';'*.WAV'});
            [xOrg,fs] = audioread(string(path)+string(file));
            nRepeat = ceil(120*fs/length(xOrg));
            x = zeros(nRepeat*length(xOrg),1);
            for ii = 1:nRepeat
                x((ii-1)*length(xOrg)+(1:length(xOrg)),1) = xOrg;
            end
            app.FILENAMEEditField.Value = file;
            app.CommonData.testSignal = x;
            app.CommonData.periodLength = length(xOrg);
            app.SpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.DIrSpecAxis.Visible = "on";
            results = 1;
        end

        function results = setWorkingDirectory(app)
            app.CommonData.messageSubj = {'実験結果等は、最下段に表示されているフォルダに',...
                '新しいフォルダを作ってその中に記録されます',...
                '『SET DIRECTORY』ボタンを押して下さい',...
                'フォルダを選ぶことができます。終わったら『OK』を押して下さい'};
            app.CommonData.messageSubE = {...
                'Acquired sounds and logs are saved by creating',...
                'unique-named folder inside the directory',...
                'shown in the bottom text bos',...
                'Please click [SET DIRECTORY] button',...
                'to select the direcotry you need',...
                'When selection is completed please click [OK]'};
            dispMessage(app);
            app.PATHEditField.Value = pwd;
            while app.SETDIRECTORYButton.Enable == "on"
                pause(0.1)
            end
            results = 1;
        end

        function results = startLogging(app)
            logFileName = "logfile.txt";
            app.CommonData.logFilePid = ...
                fopen(app.CommonData.workingPath+logFileName,"a");
            fprintf(app.CommonData.logFilePid,"START at: " + ...
                string(datetime(datetime))+"\n");
            results = 1;
        end

        function results = generateLLNPinkNoise(app)
            fs = app.CommonData.samplingFrequency;
            fx = (0:fs-1)';
            fxBi = fx;
            fxBi(fx>fs/2) = fxBi(fx>fs/2)-fs;
            fxBi(1) = fxBi(2);
            lpnSpec = 1.0 ./abs(fxBi);
            lpnSpec(abs(fxBi)<100) = 1/100*abs(lpnSpec(abs(fxBi)<100)...
                ./lpnSpec(abs(fxBi)<100));
            lpnSpec(abs(fxBi)>4000) = 1/4000*abs(lpnSpec(abs(fxBi)>4000)...
                ./lpnSpec(abs(fxBi)>4000));
            lpnSpec = lpnSpec.*exp(1i*2*pi*rand(fs,1));
            lpnSpec(end:-1:fs/2+1) = conj(lpnSpec(2:fs/2+1));
            lpnSpec(1) = abs(lpnSpec(2));
            lpnSpec(fs/2+1) = abs(lpnSpec(fs/2+1));
            llnPinkNoise = real(ifft(lpnSpec));
            xUnit = llnPinkNoise/max(abs(llnPinkNoise))*0.8;
            periodLength = length(xUnit);
            testSignal = zeros(periodLength*120,1);
            for ii = 1:120
                testSignal((ii-1)*periodLength+(1:periodLength)) = xUnit;
            end
            app.CommonData.testSignal = 0.3*testSignal/max(abs(testSignal));
            app.CommonData.periodLength = periodLength;
            app.SpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.DIrSpecAxis.Visible = "on";
            results = 1;
        end

        function results = setMessgaeLanguage(app)
            app.CommonData.messageSubj = {...
                'メッセージの言語を選んでください',...
                '上の中央にある『Language』のメニューで',...
                '日本語（JPN）と英語（EN）を選ぶことができます',...
                '----',...
                'Please select the Language for message',...
                '[Language] pull-down menu on the top-center',...
                'provides selection of Japanese (JPN) and English (EN)'};
            app.CommonData.messageSubE = {...
                'Please select the Language for message',...
                '[Language] pull-down menu on the top-center',...
                'provides selection of Japanese (JPN) and English (EN)',...
                '----',...
                'メッセージの言語を選んでください',...
                '上の中央にある『Language』のメニューで',...
                '日本語（JPN）と英語（EN）を選ぶことができます'};
            dispMessage(app);
            drawnow
            pause(1);
            while app.LanguageDropDown.Enable == "on"
                pause(0.2)
            end
            results = 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setMessgaeLanguage(app);
            setWorkingDirectory(app);
            initializeGraphics(app);
            inputGainSet(app);
            inputLevelCalibration(app);
            app.Voicememo5sButton.Enable = "on";
        end

        % Button pushed function: INPUTLEVELOKButton
        function INPUTLEVELOKButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "INPUTLEVELOK at: "+ string(datetime(datetime))+"\n");
            app.CommonData.running = 0;
            app.INPUTLEVELOKButton.Enable = "off";
            app.CALIBRATIONButton.Enable = "on";
        end

        % Button pushed function: CALIBRATIONButton
        function CALIBRATIONButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "CALIBRATION at: "+ string(datetime(datetime))+"\n");
            app.CALIBRATIONButton.Enable = "off";
            app.SPLDropDown.Enable = "on";
            inputLevelCalibrationLoop(app);
            %generateTestSignal(app);
        end

        % Value changed function: SPLDropDown
        function SPLDropDownValueChanged(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "SPLDropDown at: "+ string(datetime(datetime))+"\n");
            fprintf(app.CommonData.logFilePid, ...
                "SPL is: "+ app.SPLDropDown.Value+" (dB)\n");
            value = app.SPLDropDown.Value;
            valueDb = str2double(value);
            app.CommonData.messageSubj = {...
                '設定された音圧レベルに基づいて感度を較正します',...
                'これから2秒間較正用の試験信号音が続きます'};
            app.CommonData.messageSubE = {...
                'Calibration using the selected SLP starts',...
                'The following two second recalculate levels'};
            dispMessage(app);
            stop(app.CommonData.recorder);
            pause(0.1)
            fs = app.CommonData.samplingFrequency;
            recordblocking(app.CommonData.recorder,2);
            y = getaudiodata(app.CommonData.recorder);
            %weightFilter = weightingFilter;
            %yWeighted = weightFilter(y);
            y = y(:,1);
            aWeightFir = designAweightingFIRFilter(fs);
            yWeighted = fftfilt(aWeightFir,y);
            spl = 20*log10(std(yWeighted(fs/2+(1:fs))));
            app.CommonData.calibrationValue = valueDb - spl;
            stop(app.CommonData.player);
            app.SPLDropDown.Enable = "off";
            app.CommonData.running = 0;
            calibrationResultText = ['レベルメータの0dBの音圧レベルは ' ...
                num2str(valueDb - spl,"%8.1f") ' dBになります'];
            calibrationResultTextE = ['0dB on the bar graph is ' ...
                num2str(valueDb - spl,"%8.1f") ' dB in SPL'];
            fprintf(app.CommonData.logFilePid, ...
                "calibration cf. is: "+ num2str(valueDb - spl)+"\n");
            app.CommonData.messageSubj = {...
                '感度較正が終わりました', calibrationResultText, ...
                'ーーー',  ...
                '対話的に実時間でチェックする場合は',  ...
                '『REALTIME MEASUREMENT』',  ...
                '多数の音響的属性を精密に測定する場合は',  ...
                '『MULTI-ATTRIBUTE MEASUREMENT』', ...
                'をクリックして下さい'};
            app.CommonData.messageSubE = {...
                'Calibration completed', calibrationResultTextE, ...
                '-----',  ...
                'For realtime-interacteve test, click',  ...
                '[REALTIME MEASUREMENT] ',  ...
                'For off-line multi-attribute measurement, click',  ...
                '[MULTI-ATTRIBUTE MEASUREMENT]'};
            dispMessage(app);
            app.CalibrationdBEditField.Visible = "on";
            app.CalibrationdBEditFieldLabel.Visible = "on";
            app.CalibrationdBEditField.Value = valueDb - spl;
            app.RealtimepanelPanel.Visible = "on";
            app.MultiattributepanelPanel.Visible = "on";
        end

        % Button pushed function: REALTIMEMEASUREMENTButton
        function REALTIMEMEASUREMENTButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "REALTIMEMEASUREMENT at: "+ string(datetime(datetime))+"\n");
            app.REALTIMEMEASUREMENTButton.Enable = "off";
            app.MultiattributepanelPanel.Visible = "off";
            app.TestsignalDropDown.Enable = "on";
            app.CommonData.messageSubj = {...
                '対話的実時間チェックを始めます',...
                '試験信号『Test signal』を選んで下さい'};
            app.CommonData.messageSubE = {...
                'This is realtime-interactive mode',...
                'Please select [Test signal] from menu'};
            dispMessage(app);
            app.SpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.DIrSpecAxis.Visible = "on";
        end

        % Value changed function: TestsignalDropDown
        function TestsignalDropDownValueChanged(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "TestsignalDropDown at: "+ string(datetime(datetime))+"\n");
            value = app.TestsignalDropDown.Value;
            fprintf(app.CommonData.logFilePid, ...
                "Testsigna is: "+ value+"\n");
            messageText = [value ' が選ばれました'];
            messageTextE = [value ' is the signal'];
            switch value
                case app.TestsignalDropDown.Items{7}
                    app.CommonData.giantFFTs = "on";
                    app.CommonData.messageSubj = {messageText, ...
                        '測定に用いる信号のファイルを選んでください',...
                        '読み込むとそのファイル名が表示されます'};
                    app.CommonData.messageSubE = {messageTextE, ...
                        'Please select the sound file',...
                        'The selected file name is displayed'};
                    dispMessage(app);
                otherwise
                    app.CommonData.giantFFTs = "off";
                    app.FILENAMEEditField.Value = " ";
                    app.CommonData.messageSubj = {messageText, ...
                        '試験信号を作ります',...
                        '少し時間がかかります'};
                    app.CommonData.messageSubE = {messageTextE, ...
                        'preparing the test signal',...
                        'It takes time'};
                    dispMessage(app);
            end
            generateTestSignal(app);
        end

        % Button pushed function: STOPButton_2
        function STOPButton_2Pushed(app, event)
             fprintf(app.CommonData.logFilePid, ...
                "STOP at: "+ string(datetime(datetime))+"\n");
            app.STARTButton.Enable = "on";
            app.STOPButton_2.Enable = "off";
            app.MultiattributepanelPanel.Visible = "on";
            app.TestsignalDropDown.Enable = "on";
            app.SAVEButton.Enable = "on";
            app.REALTIMEMEASUREMENTButton.Enable = "on";
            app.MULTIATTRIBUTEMEASUREMENTButton.Enable = "on";
            switch app.TestsignalDropDown.Value
                case app.TestsignalDropDown.Items{7}
                    app.TestsignalDropDown.Value = ...
                        app.TestsignalDropDown.Items{1};
            end
            app.CommonData.running = 0;
            app.CommonData.messageSubj = {...
                '対話的実時間チェックが終わりました', ...
                '試験信号を変えることができます', ...
                '『START』で同じ信号で再開することもできます', ...
                '『SAVE』で測定された信号を記録することもできます', ...
                'ーーー',  ...
                '多数の音響的属性を精密に測定する場合は',  ...
                '『MULTI-ATTRIBUTE MEASUREMENT』', ...
                'をクリックして下さい'};
            app.CommonData.messageSubE = {...
                'interactive measurement completed', ...
                'You can select other test siganl or', ...
                'Click [START] restarts the test or', ...
                'Click [SAVE] saves the acquired data', ...
                'ーーー',  ...
                'For off-line multi-attribute measurement, click',  ...
                '[MULTI-ATTRIBUTE MEASUREMENT] '};
            dispMessage(app);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "START at: "+ string(datetime(datetime))+"\n");
            app.STARTButton.Enable = "off";
            app.SAVEButton.Enable = "off";
            app.STOPButton_2.Enable = "on";
            realtimeMeasurementLoop(app);
        end

        % Button pushed function: MULTIATTRIBUTEMEASUREMENTButton
        function MULTIATTRIBUTEMEASUREMENTButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "MULTIATTRIBUTEMEASUREMENT at: "+ string(datetime(datetime))+"\n");
            app.MULTIATTRIBUTEMEASUREMENTButton.Enable = "off";
            YData = get(app.CommonData.indrectHandle,"YData");
            set(app.CommonData.indrectHandle,"YData",YData*NaN);
            app.CommonData.messageSubj = { ...
                '多数の音響的属性を精密に測定します',...
                '『START』をクリックして測定を始めて下さい',...
                ['試験信号が22秒再生されます', ...
                '試験信号が終わってしばらくすると結果が表示されます']};
            app.CommonData.messageSubE = { ...
                'This is off-line multi-attribute measurement mode',...
                'Click [START] to start the test',...
                ['The test signal lasts 22 seconds', ...
                'After completion results is shown']};
            dispMessage(app);
            app.STARTButton_2.Enable = "on";
            app.DIrSpecAxis.Visible = "on";
            app.pRespAxis.Visible = "on";
            app.SpecAxis.Visible = "on";
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "START_2 at: "+ string(datetime(datetime))+"\n");
            app.ABORTButton.Enable = "on";
            app.SAVEButton_2.Enable = "off";
            app.STARTButton_2.Enable = "off";
            app.CommonData.messageSubj = {...
                '多数の音響的属性の精密測定を開始します', ...
                '『ABORT』で測定を中断することができます'};
            app.CommonData.messageSubE = {...
                'multi-attribute measurement is started', ...
                'Clink [ABORT] to abort the measurement'};
            dispMessage(app);
            multiAttributeMeasurement(app);
        end

        % Button pushed function: SETDIRECTORYButton
        function SETDIRECTORYButtonPushed(app, event)
            selpath = uigetdir(pwd);
            if selpath ~= 0
                app.PATHEditField.Value = selpath;
                t = datetime(datetime,'format','yyyyMMddHHmmss');
                dirName = "acMes" + string(t);
                app.CommonData.workingPath = selpath+"/"+dirName+"/";
                mkdir(app.CommonData.workingPath);
                app.SETDIRECTORYButton.Enable = "off";
                app.INPUTLEVELOKButton.Enable = "on";
                startLogging(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isfield(app.CommonData,"logFilePid")
                fprintf(app.CommonData.logFilePid, ...
                    "Quit at: "+ string(datetime(datetime))+"\n");
                fclose(app.CommonData.logFilePid);
            end
            delete(app)
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "ABORT at: "+ string(datetime(datetime))+"\n");
            app.STARTButton_2.Enable = "on";
            app.ABORTButton.Enable = "off";
            app.REALTIMEMEASUREMENTButton.Enable = "on";
            app.MULTIATTRIBUTEMEASUREMENTButton.Enable = "on";
            app.CommonData.running = 0;
            app.CommonData.messageSubj = {...
                '多数の音響的属性測定が中断されました', ...
                '『START』でやり直すこともできます'};
            app.CommonData.messageSubE = {...
                'Multi-attribute measurement is aborted', ...
                'Click [START] restarts the test'};
            dispMessage(app);
        end

        % Button pushed function: SAVEButton_2
        function SAVEButton_2Pushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "SAVE in multi-mes. at: "+ string(datetime(datetime))+"\n");
            fs = app.CommonData.samplingFrequency;
            t = datetime(datetime,'format','yyyyMMddHHmmss');
            y = app.CommonData.acquiredSignal;
            app.CommonData.commentString = 'multiMes';
            commentString = app.CommonData.commentString;
            outFileName = "acMultiMes" + string(t) +".wav";
            outFullPath = app.CommonData.workingPath+"/"+outFileName;
            audiowrite(outFullPath,y,fs,"BitsPerSample",32,"Comment",commentString);
            app.CommonData.soundOutFileName = outFileName;
            fprintf(app.CommonData.logFilePid, ...
                "SAVED at: "+ string(datetime(datetime))+"\n");
            fprintf(app.CommonData.logFilePid, ...
                "filename is: "+ outFileName +"\n");
            app.SAVEButton_2.Enable = "off";
            app.REALTIMEMEASUREMENTButton.Enable = "on";
            app.MULTIATTRIBUTEMEASUREMENTButton.Enable = "on";
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "SAVE in RT-mes. at: "+ string(datetime(datetime))+"\n");
            fs = app.CommonData.samplingFrequency;
            t = datetime(datetime,'format','yyyyMMddHHmmss');
            y = app.CommonData.acquiredSignal;
            app.CommonData.commentString = app.TestsignalDropDown.Value;
            commentString = app.CommonData.commentString;
            artistString = string(app.FILENAMEEditField.Value);
            outFileName = "acRealTMes" + string(t) +".wav";
            outFullPath = app.CommonData.workingPath+"/"+outFileName;
            audiowrite(outFullPath,y,fs,"BitsPerSample",32, ...
                "Comment",commentString,"Artist",artistString);
            app.CommonData.soundOutFileName = outFileName;
            fprintf(app.CommonData.logFilePid, ...
                "SAVED at: "+ string(datetime(datetime))+"\n");
            fprintf(app.CommonData.logFilePid, ...
                "filename is: "+ outFileName +"\n");
            app.SAVEButton.Enable = "off";
            app.REALTIMEMEASUREMENTButton.Enable = "on";
            app.MULTIATTRIBUTEMEASUREMENTButton.Enable = "on";
        end

        % Button pushed function: SELECTFILEButton
        function SELECTFILEButtonPushed(app, event)

        end

        % Value changed function: LanguageDropDown
        function LanguageDropDownValueChanged(app, event)
            value = app.LanguageDropDown.Value;
            switch value
                case app.LanguageDropDown.Items{1}
                    app.LanguageDropDown.Enable = "off";
                case app.LanguageDropDown.Items{2}
                    app.LanguageDropDown.Enable = "off";
            end
        end

        % Button pushed function: Voicememo5sButton
        function Voicememo5sButtonPushed(app, event)
            fprintf(app.CommonData.logFilePid, ...
                "Voice memo at: "+ string(datetime(datetime))+"\n");
            app.tCount.Visible = "on";
            fs = app.CommonData.samplingFrequency;
            t = datetime(datetime,'format','yyyyMMddHHmmss');
            outFileName = "voiceMemo" + string(t) +".wav";
            outFullPath = app.CommonData.workingPath+"/"+outFileName;
            app.CommonData.recorder = audiorecorder(fs,24,1);
            recorder = 0;
            timerCount = -2;
            while timerCount < 5
                pause(0.1);
                timerCount = timerCount+0.1;
                app.tCount.Text = num2str(timerCount,"%4.1f");
                if timerCount > 0
                    app.ONLamp.Visible = "on";
                    switch recorder
                        case 0
                            record(app.CommonData.recorder);
                            recorder = 1;
                    end
                end
                drawnow
            end
            y = getaudiodata(app.CommonData.recorder);
            stop(app.CommonData.recorder);
            audiowrite(outFullPath,y/max(abs(y))*0.5,fs,"BitsPerSample",24);
            app.ONLamp.Visible = "off";
            app.tCount.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.levelIndicator = uiaxes(app.UIFigure);
            title(app.levelIndicator, 'Level')
            ylabel(app.levelIndicator, 'level (dB rel. MSB)')
            zlabel(app.levelIndicator, 'Z')
            app.levelIndicator.YLim = [-100 0];
            app.levelIndicator.XTick = [];
            app.SpecAxis = uiaxes(app.UIFigure);
            title(app.SpecAxis, 'Frequency Responce')
            xlabel(app.SpecAxis, 'Frequency (Hz)')
            ylabel(app.SpecAxis, 'Gain (rel. dB)')
            zlabel(app.SpecAxis, 'Z')
            app.SpecAxis.XLim = [20 20000];
            app.SpecAxis.YLim = [-80 40];
            app.SpecAxis.XScale = 'log';
            app.pRespAxis = uiaxes(app.UIFigure);
            title(app.pRespAxis, 'power response')
            xlabel(app.pRespAxis, 'Time (s)')
            ylabel(app.pRespAxis, 'Level (rel. dB)')
            zlabel(app.pRespAxis, 'Z')
            app.pRespAxis.YLim = [-90 0];
            app.DIrSpecAxis = uiaxes(app.UIFigure);
            title(app.DIrSpecAxis, 'Frequency Responce')
            xlabel(app.DIrSpecAxis, 'Frequency (Hz)')
            ylabel(app.DIrSpecAxis, 'Gain (rel. dB)')
            zlabel(app.DIrSpecAxis, 'Z')
            app.DIrSpecAxis.XLim = [20 20000];
            app.DIrSpecAxis.YLim = [-60 10];
            app.DIrSpecAxis.XScale = 'log';
            app.AcousticenvironmentcheckerLabel = uilabel(app.UIFigure);
            app.AcousticenvironmentcheckerLabel.Text = 'Acoustic environment checker';
            app.ControlpanelPanel = uipanel(app.UIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.MessageTextAreaLabel = uilabel(app.ControlpanelPanel);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.ControlpanelPanel);
            app.INPUTLEVELOKButton = uibutton(app.ControlpanelPanel, 'push');
            app.INPUTLEVELOKButton.ButtonPushedFcn = createCallbackFcn(app, @INPUTLEVELOKButtonPushed, true);
            app.INPUTLEVELOKButton.Enable = 'off';
            app.INPUTLEVELOKButton.Text = 'INPUT LEVEL OK';
            app.CALIBRATIONButton = uibutton(app.ControlpanelPanel, 'push');
            app.CALIBRATIONButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATIONButtonPushed, true);
            app.CALIBRATIONButton.Enable = 'off';
            app.CALIBRATIONButton.Text = 'CALIBRATION';
            app.SPLDropDownLabel = uilabel(app.ControlpanelPanel);
            app.SPLDropDownLabel.Enable = 'off';
            app.SPLDropDownLabel.Text = 'SPL';
            app.SPLDropDown = uidropdown(app.ControlpanelPanel);
            app.SPLDropDown.Items = {'90', '89', '88', '87', '86', '85', '84', '83', '82', '81', '80', '79', '78', '77', '76', '75', '74', '73', '72', '71', '70', '69', '68', '67', '66', '65', '0'};
            app.SPLDropDown.ValueChangedFcn = createCallbackFcn(app, @SPLDropDownValueChanged, true);
            app.SPLDropDown.Enable = 'off';
            app.SPLDropDown.Value = '90';
            app.RealtimepanelPanel = uipanel(app.ControlpanelPanel);
            app.RealtimepanelPanel.Title = 'Realtime panel';
            app.RealtimepanelPanel.Visible = 'off';
            app.REALTIMEMEASUREMENTButton = uibutton(app.RealtimepanelPanel, 'push');
            app.REALTIMEMEASUREMENTButton.ButtonPushedFcn = createCallbackFcn(app, @REALTIMEMEASUREMENTButtonPushed, true);
            app.REALTIMEMEASUREMENTButton.Text = 'REALTIME MEASUREMENT';
            app.TestsignalDropDownLabel = uilabel(app.RealtimepanelPanel);
            app.TestsignalDropDownLabel.Enable = 'off';
            app.TestsignalDropDownLabel.Text = 'Test signal';
            app.TestsignalDropDown = uidropdown(app.RealtimepanelPanel);
            app.TestsignalDropDown.Items = {'--Select signal--', 'CAPRICEP', 'Bakuon-SG', 'SCN-Pink', 'Log-Swept-Sine', 'MLS', 'File_input'};
            app.TestsignalDropDown.ValueChangedFcn = createCallbackFcn(app, @TestsignalDropDownValueChanged, true);
            app.TestsignalDropDown.Enable = 'off';
            app.TestsignalDropDown.Value = '--Select signal--';
            app.STARTButton = uibutton(app.RealtimepanelPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.STOPButton_2 = uibutton(app.RealtimepanelPanel, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_2Pushed, true);
            app.STOPButton_2.Enable = 'off';
            app.STOPButton_2.Text = 'STOP';
            app.SAVEButton = uibutton(app.RealtimepanelPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.FILENAMEEditFieldLabel = uilabel(app.RealtimepanelPanel);
            app.FILENAMEEditFieldLabel.Text = 'FILE NAME';
            app.FILENAMEEditField = uieditfield(app.RealtimepanelPanel, 'text');
            app.SELECTFILEButton = uibutton(app.RealtimepanelPanel, 'push');
            app.SELECTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTFILEButtonPushed, true);
            app.SELECTFILEButton.Enable = 'off';
            app.SELECTFILEButton.Text = 'SELECT FILE';
            app.NChDropDownLabel = uilabel(app.RealtimepanelPanel);
            app.NChDropDownLabel.Text = 'N-Ch';
            app.NChDropDown = uidropdown(app.RealtimepanelPanel);
            app.NChDropDown.Items = {'1', '2'};
            app.NChDropDown.Value = '1';
            app.MultiattributepanelPanel = uipanel(app.ControlpanelPanel);
            app.MultiattributepanelPanel.Title = 'Multi-attribute panel';
            app.MultiattributepanelPanel.Visible = 'off';
            app.MULTIATTRIBUTEMEASUREMENTButton = uibutton(app.MultiattributepanelPanel, 'push');
            app.MULTIATTRIBUTEMEASUREMENTButton.ButtonPushedFcn = createCallbackFcn(app, @MULTIATTRIBUTEMEASUREMENTButtonPushed, true);
            app.MULTIATTRIBUTEMEASUREMENTButton.Text = 'MULTI-ATTRIBUTE MEASUREMENT';
            app.STARTButton_2 = uibutton(app.MultiattributepanelPanel, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Enable = 'off';
            app.STARTButton_2.Text = 'START';
            app.ABORTButton = uibutton(app.MultiattributepanelPanel, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Enable = 'off';
            app.ABORTButton.Text = 'ABORT';
            app.SAVEButton_2 = uibutton(app.MultiattributepanelPanel, 'push');
            app.SAVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_2Pushed, true);
            app.SAVEButton_2.Enable = 'off';
            app.SAVEButton_2.Text = 'SAVE';
            app.CalibrationdBEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationdBEditFieldLabel.Text = 'Calibration dB';
            app.CalibrationdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationdBEditField.ValueDisplayFormat = '%11.1f';
            app.CalibrationdBEditField.Editable = 'off';
            app.CalibrationdBEditField.Visible = 'off';
            app.CounterEditFieldLabel = uilabel(app.UIFigure);
            app.CounterEditFieldLabel.Text = 'Counter';
            app.CounterEditField = uieditfield(app.UIFigure, 'numeric');
            app.CounterEditField.ValueDisplayFormat = '%05.1f';
            app.CounterEditField.Editable = 'off';
            app.ONLampLabel = uilabel(app.UIFigure);
            app.ONLampLabel.Visible = 'off';
            app.ONLampLabel.Text = 'ON';
            app.ONLamp = uilamp(app.UIFigure);
            app.ONLamp.Visible = 'off';
            app.SETDIRECTORYButton = uibutton(app.UIFigure, 'push');
            app.SETDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SETDIRECTORYButtonPushed, true);
            app.SETDIRECTORYButton.Text = 'SET DIRECTORY';
            app.PATHEditFieldLabel = uilabel(app.UIFigure);
            app.PATHEditFieldLabel.Text = 'PATH';
            app.PATHEditField = uieditfield(app.UIFigure, 'text');
            app.LanguageDropDownLabel = uilabel(app.UIFigure);
            app.LanguageDropDownLabel.Text = 'Language';
            app.LanguageDropDown = uidropdown(app.UIFigure);
            app.LanguageDropDown.Items = {'JPN', 'EN', '--select--'};
            app.LanguageDropDown.ValueChangedFcn = createCallbackFcn(app, @LanguageDropDownValueChanged, true);
            app.LanguageDropDown.Value = '--select--';
            app.Voicememo5sButton = uibutton(app.UIFigure, 'push');
            app.Voicememo5sButton.ButtonPushedFcn = createCallbackFcn(app, @Voicememo5sButtonPushed, true);
            app.Voicememo5sButton.Enable = 'off';
            app.Voicememo5sButton.Text = 'Voice memo 5s';
            app.tCount = uilabel(app.UIFigure);
            app.tCount.Visible = 'off';
            app.tCount.Text = 'Tcount';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acousticConditionChecker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end