classdef reverbeLmbrdSpeechTesterRev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoisesettingPanel               matlab.ui.container.Panel
        NoiseDropDown                   matlab.ui.control.DropDown
        NoiseDropDownLabel              matlab.ui.control.Label
        LevelAdBDropDown                matlab.ui.control.DropDown
        LevelAdBDropDownLabel           matlab.ui.control.Label
        AdjustheadphonelevelPanel       matlab.ui.container.Panel
        AbortButton_2                   matlab.ui.control.Button
        Measure_latency                 matlab.ui.control.Button
        Label_time_2                    matlab.ui.control.Label
        TimesLabel_2                    matlab.ui.control.Label
        OKdoneButton                    matlab.ui.control.Button
        AdjustheadphoneButton           matlab.ui.control.Button
        dBremuPaLabel                   matlab.ui.control.Label
        LabelforDB_2                    matlab.ui.control.Label
        VoiceMemoCountdown              matlab.ui.control.Label
        RecordingLamp                   matlab.ui.control.Lamp
        RecordingLampLabel              matlab.ui.control.Label
        Voicememo5sButton               matlab.ui.control.Button
        CalibrationPanel                matlab.ui.container.Panel
        SPL80dBadjustedCheckBox         matlab.ui.control.CheckBox
        SensitivityOKButton             matlab.ui.control.Button
        AbortButton                     matlab.ui.control.Button
        Label_time                      matlab.ui.control.Label
        TimesLabel                      matlab.ui.control.Label
        CalibrationButton               matlab.ui.control.Button
        dBreMSBLabel                    matlab.ui.control.Label
        LabelforDB                      matlab.ui.control.Label
        ReverberationimpulseresponsePanel  matlab.ui.container.Panel
        MagSlider                       matlab.ui.control.Slider
        MagSliderLabel                  matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        FileDropDownLabel               matlab.ui.control.Label
        FolderTextArea                  matlab.ui.control.TextArea
        FolderTextAreaLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AudiointerfacePanel             matlab.ui.container.Panel
        READYGOButton                   matlab.ui.control.Button
        SamplingfrequensyHzTextArea     matlab.ui.control.TextArea
        SamplingfrequensyHzTextAreaLabel  matlab.ui.control.Label
        BuffersizesamplesDropDown       matlab.ui.control.DropDown
        BuffersizesamplesDropDownLabel  matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ReverberationwithLombardspeechtesterLabel  matlab.ui.control.Label
        ResponsepanelPanel              matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        ExperimentsettingPanel          matlab.ui.container.Panel
        FeedbackleveldBDropDown         matlab.ui.control.DropDown
        FeedbackleveldBDropDownLabel    matlab.ui.control.Label
        FinishexperimentButton          matlab.ui.control.Button
        STOPTrialButton                 matlab.ui.control.Button
        STARTTrialButton                matlab.ui.control.Button
    end

    
    %       Copyright 2023 Hideki Kawahara
%
%   Licensed under the Apache License, Version 2.0 (the "License");
%   you may not use this file except in compliance with the License.
%   You may obtain a copy of the License at
%
%       http://www.apache.org/licenses/LICENSE-2.0
%
%   Unless required by applicable law or agreed to in writing, software
%   distributed under the License is distributed on an "AS IS" BASIS,
%   WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
%   See the License for the specific language governing permissions and
%   limitations under the License.

    properties (Access = private)
        CommonData % Description
    end
    
    methods (Access = private)
        
        function results = selectReverbeFile(app)
            nItem = 0;
            while nItem == 0
                selpath = uigetdir(pwd,"音声のディレクトリを選んで下さい");
                app.CommonData.basePath = selpath;
                dirContents = dir(string(selpath)+"/*.wav");
                nItem = length(dirContents);
            end
            app.FolderTextArea.Value = string(selpath);
            bbb = string(nItem);
            for ii = 1:nItem
                bbb(ii) = string(dirContents(ii).name);
            end
            %bbb = bbb(:);
            items = ["以下から選択して下さい";string(bbb(:))];
            app.FileDropDown.Items = items;
            app.FileDropDown.Value = items(1);
            app.TextArea.Value = ["Fileの項目の中から"; ...
                "残響のインパルス応答のファイルを選んで下さい"];
            results = 1;
        end
        
        function results = calibrateInput(app)
            fs = app.CommonData.samplingFrequency;
            t1sec = (1:round(fs))'/fs;
            fc = 1000;
            fL = round(fc*2^(-1/2));
            fU = round(fc*2^(1/2));
            xnUnit = zeros(round(fs),1);
            for fcur = fL:fU
                xnUnit = xnUnit + sin(2*pi*fcur*t1sec+rand(1,1)*2*pi);
            end
            xnUnit = xnUnit/std(xnUnit)/10; % -20dB rel. MSB
            signalDuration = 60;
            xTest = zeros(round(fs)*signalDuration,1);
            for ii = 1:signalDuration
                xTest((ii-1)*round(fs)+(1:round(fs))) = xnUnit;
            end
            app.CommonData.xnUnit = xnUnit;
            app.CommonData.testSignal = xTest;
            %-----------------
            ioBufferSize = 2048;
            playrec = audioPlayerRecorder(fs,"BitDepth",...
                "24-bit integer","BufferSize",ioBufferSize,...
                "Device",app.CommonData.ioDevice,"SupportVariableSize",true, ...
                "RecorderChannelMapping",1,"PlayerChannelMapping", ...
                [1,2]);
            %record(playrec);
            pause(0.5);
            app.CommonData.running = "on";
            app.CommonData.aborted = "no";
            app.AbortButton.Enable = "on";
            count = 0;
            maxCount = floor(length(xTest)/ioBufferSize);
            app.CommonData.currntLevel = -100;
            oneSecBuffer = zeros(round(fs),1);
            while app.CommonData.running == "on" && count < maxCount
                oneSecBuffer(1:end-ioBufferSize) = ...
                    oneSecBuffer(ioBufferSize+1:end);
                outData = xTest(count*ioBufferSize+(1:ioBufferSize));
                readData = playrec([outData outData]);
                oneSecBuffer(end-ioBufferSize+1:end) = readData;
                app.CommonData.currntLevel = 20*log10(std(oneSecBuffer));
                app.LabelforDB.Text = ...
                    num2str(app.CommonData.currntLevel,"%5.1f");
                count = count + 1;
                app.Label_time.Text = num2str(count*ioBufferSize/fs,"%5.2f");
                drawnow
            end
            release(app.CommonData.playrec);
            if app.CommonData.running == "on"
                app.TextArea.Value = ["試験信号が終わりました"; ...
                    "Calibration ボタンを押すと、また較正することができます"; ...
                    "1秒後に Calibration ボタンを押すことができるようになります"];
                pause(1)
                app.CalibrationButton.Enable = "on";
                app.AbortButton.Enable = "off";
            elseif app.CommonData.aborted == "yes"
                app.TextArea.Value = ["較正が中断されました"; ...
                    "Calibration ボタンを押すと、また較正することができます"; ...
                    "1秒後に Calibration ボタンを押すことができるようになります"];
                pause(1)
                app.CalibrationButton.Enable = "on";
                app.AbortButton.Enable = "off";
            end
            %-----------------
            results = 1;
        end
        
        function results = adjustHeadphone(app)
            app.TextArea.Value = ["ヘッドフォンのレベルを調整します"; ...
                "出力を最小にして下さい。"; ...
                "ヘッドフォンを平面に軽く押し当てて、中にマイクを入れて下さい";...
                "人工耳を使う場合にはヘッドフォンとマイクを人工耳に装着して下さい";...
                "準備ができたら Adjust headphone ボタンを押して下さい"];
             app.AdjustheadphonelevelPanel.Visible = "on";
             app.AdjustheadphonelevelPanel.Enable = "on";
            results = 1;
        end
        
        function results = adjustLevelBody(app)
            fs = app.CommonData.samplingFrequency;
            xTest = app.CommonData.testSignal;
            xTest = xTest/std(xTest)*10^((80-app.CommonData.calibrationdB)/20);
            ioBufferSize = 2048;
            playrec = audioPlayerRecorder(fs,"BitDepth",...
                "24-bit integer","BufferSize",ioBufferSize,...
                "Device",app.CommonData.ioDevice,"SupportVariableSize",true, ...
                "PlayerChannelMapping",[1,2]);
            pause(0.5);
            app.CommonData.running = "on";
            app.OKdoneButton.Enable = "on";
            app.CommonData.aborted = "no";
            app.AbortButton_2.Enable = "on";
            maxCount = floor(length(xTest)/ioBufferSize);
            app.CommonData.currntLevel = -100;
            app.CommonData.oneSecBuffer = zeros(round(fs),1);
            app.CommonData.oneSecOutBuffer = zeros(round(fs),1);
            count = 0;
            while app.CommonData.running == "on" && count < maxCount
                app.CommonData.oneSecBuffer(1:end-ioBufferSize) = ...
                    app.CommonData.oneSecBuffer(ioBufferSize+1:end);
                app.CommonData.oneSecOutBuffer(1:end-ioBufferSize) = ...
                    app.CommonData.oneSecOutBuffer(ioBufferSize+1:end);
                outData = xTest(count*ioBufferSize+(1:ioBufferSize));
                app.CommonData.oneSecOutBuffer(end-ioBufferSize+1:end) = ...
                    outData;
                readData = playrec([outData outData]);
                app.CommonData.oneSecBuffer(end-ioBufferSize+1:end) = ...
                    readData;
                app.CommonData.currntLevel = ...
                    20*log10(std(app.CommonData.oneSecBuffer)) ...
                    + app.CommonData.calibrationdB;
                app.LabelforDB_2.Text = ...
                    num2str(app.CommonData.currntLevel,"%5.1f");
                count = count + 1;
                app.Label_time_2.Text = num2str(count*ioBufferSize/fs,"%5.2f");
                drawnow
            end
            release(app.CommonData.playrec);
            if app.CommonData.running == "on"
                app.TextArea.Value = ["試験信号が終わりました"; ...
                    "Adjust headphone ボタンを押すと、また調整することができます"; ...
                    "1秒後に Adjust headphone ボタンを押すことができるようになります"];
                pause(1)
                app.AdjustheadphoneButton.Enable = "on";
                app.AbortButton_2.Enable = "off";
                app.OKdoneButton.Enable = "off";
            elseif app.CommonData.aborted == "yes"
                app.TextArea.Value = ["ヘッドフォンのレベルの調整が中断されました"; ...
                    "Adjust headphone ボタンを押すと、また調整することができます"; ...
                    "1秒後に Adjust headphone ボタンを押すことができるようになります"];
                pause(1)
                app.AdjustheadphoneButton.Enable = "on";
                app.AbortButton_2.Enable = "off";
                app.OKdoneButton.Enable = "off";
            end
            results = 1;
        end
        
        function results = setReportDirectory(app)
            selpath = uigetdir(pwd,"フォルダーを作る場所を選んで下さい");
            dirPath = append(selpath,"/",app.CommonData.reportDirName);
            mkdir(dirPath);
            app.CommonData.logFileName = "revSpTestLog.txt";
            fullPathName = append(dirPath,"/",app.CommonData.logFileName);
            app.CommonData.logFid = fopen(fullPathName,"a");
            fprintf(app.CommonData.logFid, 'Start, %s\n', datetime);
            app.CommonData.resultDir = dirPath;
            %app.CommonData.logFileFID = 
            results = 1;
        end
        
        function results = reverberationTestBody(app)
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.revImpulse;
            x = x/sqrt(sum(x.^2));
            ioBlockL = str2double(app.BuffersizesamplesDropDown.Value);
            fftl = 2^floor(log2(length(x)+2*ioBlockL));
            hReverbe = fft(x,fftl);
            maxRecordingDuratio = 1*60; % in second (1 munutes)
            maxSample = round(maxRecordingDuratio*fs);
            caputuredSignal = zeros(maxSample,1);
            stimulusData = zeros(maxSample,1);
            driverName = app.CommonData.ioDevice;
            app.CommonData.playrec = ...
                audioPlayerRecorder(fs,"Device", driverName, ...
                "BitDepth","24-bit integer" ...
                ,"SupportVariableSize",true,"BufferSize",ioBlockL, ...
                "PlayerChannelMapping",[1 2]);
            nIteration = floor(maxSample/ioBlockL);
            currentIteration = 0;
            buffVec = zeros(ioBlockL,1);
            overRunRecord = zeros(nIteration,1);
            underRunRecord = zeros(nIteration,1);
            soundBuff = zeros(fftl,1);
            feedbackLevel = 10 ^ (str2double(app.FeedbackleveldBDropDown.Value)/20);
            app.CommonData.running = "on";
            pause(0.2)
            while app.CommonData.running == "on" && currentIteration < nIteration
                currentIteration = currentIteration + 1;
                [readData,nUnderruns,nOverruns] = ...
                    app.CommonData.playrec([buffVec buffVec]*feedbackLevel);
                caputuredSignal((currentIteration-1)*ioBlockL+(1:ioBlockL)) ...
                    = readData;
                stimulusData((currentIteration-1)*ioBlockL+(1:ioBlockL)) ...
                    = buffVec;
                underRunRecord(currentIteration) = nUnderruns;
                overRunRecord(currentIteration) = nOverruns;
                soundBuff(1:fftl-ioBlockL) = soundBuff(ioBlockL+1:fftl);
                soundBuff(fftl-ioBlockL+1:fftl) = readData;
                resp = real(ifft(fft(soundBuff).*hReverbe));
                buffVec = resp(fftl-ioBlockL+1:fftl);
                app.Label_2.Text = num2str(currentIteration*ioBlockL/fs,"%5.2f");
                drawnow limitrate
            end
            release(app.CommonData.playrec);
            disp(size(readData));
            app.FeedbackleveldBDropDown.Enable = "on";
            app.TextArea.Value = ["実験の試行が終わりました";...
                "START Trial ボタンを押すと、次の実験の試行が始まります";...
                "全ての試行が終わったら、Finish experiment ボタンを押して実験を終了してください"];
            app.CommonData.caputuredSignal = ...
                caputuredSignal(1:currentIteration*ioBlockL);
            app.CommonData.stimulusData = ...
                stimulusData(1:currentIteration*ioBlockL);
            app.CommonData.underRunRecord = underRunRecord(1:currentIteration);
            app.CommonData.overRunRecord = overRunRecord(1:currentIteration);
            saveTrialRecord(app);
            results = 1;
        end
        
        function results = saveTrialRecord(app)
            fs = app.CommonData.samplingFrequency;
            outNameBody = append("rialR", ...
                string(datetime("now","Format","uuuuMMdd'T'HHmmss")));
            capSignalFullpath = ...
                append(app.CommonData.resultDir,"/",outNameBody,"cap.wav");
            stimulusFullpath = ...
                append(app.CommonData.resultDir,"/",outNameBody,"stm.wav");
            conditionFullpath = ...
                append(app.CommonData.resultDir,"/",outNameBody,"cond.mat");
            audiowrite(capSignalFullpath,app.CommonData.caputuredSignal, ...
                fs,"BitsPerSample",24);
            audiowrite(stimulusFullpath,app.CommonData.stimulusData, ...
                fs,"BitsPerSample",24);
            trialConditions = struct;
            trialConditions.ioDevice = app.CommonData.ioDevice;
            trialConditions.ioDevice = app.CommonData.ioDevice;
            trialConditions.reverberationFile = ...
                app.CommonData.reverberationFile;
            trialConditions.noiseType = ...
                app.NoiseDropDown.Value;
            switch app.NoiseDropDown.Value
                case app.NoiseDropDown.Items{2}
                    trialConditions.noiseFile = ...
                        app.CommonData.noiseFile;
            end
            trialConditions.noiseLevel = ...
                app.LevelAdBDropDown.Value;
            trialConditions.basePath = app.CommonData.basePath;
            trialConditions.FeedbackLevel = ...
                app.FeedbackleveldBDropDown.Value;
            trialConditions.underRunRecord = app.CommonData.underRunRecord;
            trialConditions.overRunRecord = app.CommonData.overRunRecord;
            save(conditionFullpath,"trialConditions");
            results = 1;
        end
        
        function results = fetchNoiseFile(app)
            results = 1;
        end
        
        function results = generateNoise(app)
            value = app.NoiseDropDown.Value;
            %ddd = app.NoiseDropDown.Items{2};
            switch value
                case app.NoiseDropDown.Items{1} % none
                case app.NoiseDropDown.Items{2} % file inpur
                case app.NoiseDropDown.Items{3} % pink
                case app.NoiseDropDown.Items{4} % white
                case app.NoiseDropDown.Items{5} % hoth
                case app.NoiseDropDown.Items{6} % babble
            end
            results = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t1 = string(datetime("now",'Format','yMMdd'));
            t2 = string(datetime("now",'Format','HHmmss'));
            reportDirName = append("revSpeechTest",t1,"T",t2);
            app.TextArea.Value = ["実験結果を記録するために"; ...
                reportDirName;...
                "という名前のフォルダーを作ります。作る場所を指定して下さい"];
            app.CommonData.reportDirName = reportDirName;
            app.CommonData.noiseFile = [];
            setReportDirectory(app);
            playrec = audioPlayerRecorder;
            devices = getAudioDevices(playrec);
            devName = ["以下から選んで下さい";string(devices')];
            app.AdjustheadphonelevelPanel.Visible = "on";
            app.NoiseDropDown.Visible = "on";
            app.DeviceDropDown.Items = devName;
            app.TextArea.Value = ["左下の Audio interface のメニューから"; ...
                "使用するオーディオインタフェース（Device）を選んで下さい"];
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            value = app.DeviceDropDown.Value;
            fs = 44100;
            ioBufferSize = str2double(app.BuffersizesamplesDropDown.Value);
            try
            app.CommonData.playrec = audioPlayerRecorder(fs,"BitDepth",...
                "24-bit integer","BufferSize",ioBufferSize,...
                "Device",value,"SupportVariableSize",true);
            catch
                app.TextArea.Value = ["インタフェースの初期化に失敗しました";...
                    "別のオーディオインタフェース（Device）を選んで下さい"];
                return;
            end
            app.TextArea.Value = ["インタフェースが正しく設定されたことを確認しました"; ...
                "次に、残響のインパルス応答（.wav）を選びます";...
                "その前に、残響のファイルが入っているディレクトリを選んで下さい"];
            app.CommonData.ioDevice = value;
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'IOdevice selection, %s\n', ...
                append(value, delimiter, string(datetime)));
            app.DeviceDropDown.Enable = "off";
            release(app.CommonData.playrec);
            app.ReverberationimpulseresponsePanel.Enable = "on";
            selectReverbeFile(app);
            app.Voicememo5sButton.Visible = "on";
            app.Voicememo5sButton.Enable = "on";
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            value = app.FileDropDown.Value;
            try
            [x,fs] = audioread(app.CommonData.basePath + "/" ...
                + value);
            x = x(:,1);% force left channel if it is multi
            catch
                app.TextArea.Value = ["インパルス応答の読み込みに失敗しました";...
                    "別のファイルを選んで下さい"];
                return;
            end
            app.CommonData.reverberationFile = value;
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'impulse response directory, %s\n', ...
                append(app.CommonData.basePath, delimiter, string(datetime)));
            fprintf(app.CommonData.logFid, 'impulse response file, %s\n', ...
                append(value, delimiter, string(datetime)));
            fprintf(app.CommonData.logFid, 'Sampling frequency (Hz), %s\n', ...
                append(num2str(fs), delimiter, string(datetime)));
            app.TextArea.Value = ["インパルス応答を読み込みました";...
                "確認して下さい。よければ、システムの較正に進みます";...
                "右のパネルの Calibration ボタンを押して下さい。較正用の音が出ます"];
            app.SamplingfrequensyHzTextArea.Value = num2str(fs);
            app.UIAxes.Visible = "on";
            hold(app.UIAxes,"off");
            tt = (1:length(x))/fs;
            app.CommonData.dBHandle = plot(app.UIAxes,tt,20*log10(abs(x)/max(abs(x))));
            hold(app.UIAxes,"on");
            app.CommonData.waveHandle = plot(app.UIAxes,tt,40*x/max(abs(x))-40, ...
                "LineWidth",2);
            xmag = 2^app.MagSlider.Value;
            axis(app.UIAxes,[0 tt(end)/xmag -80 0]);
            app.CommonData.samplingFrequency = fs;
            app.CommonData.revImpulse = x;
            app.CalibrationPanel.Enable = "on";
            app.CalibrationButton.Enable = "on";
        end

        % Value changing function: MagSlider
        function MagSliderValueChanging(app, event)
            changingValue = event.Value;
            xmag = 2^changingValue;
            xDada = get(app.CommonData.waveHandle,"XData");
            set(app.UIAxes,"XLim",[0 xDada(end)/xmag]);
            drawnow limitrate
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            app.CalibrationButton.Enable = "off";
            app.AbortButton.Enable = "on";
            app.AbortButton.Visible = "on";
            app.Label_time.Enable = "on";
            app.Label_time.Visible = "on";
            app.dBreMSBLabel.Visible = "on";
            app.TimesLabel.Visible = "on";
            app.TimesLabel.Enable = "on";
            app.LabelforDB.Visible = "on";
            app.LabelforDB.Enable = "on";
            app.SPL80dBadjustedCheckBox.Enable = "on";
            fprintf(app.CommonData.logFid, 'Calibration button pushed, %s\n', ...
                string(datetime));
            app.TextArea.Value = ["オーディオインタフェースの出力をまず調整します。音は60秒続きます";...
                "ヘッドフォン出力と、マイクの入力は最小にしておいて下さい"; ...
                "マイクの位置での音圧レベルを騒音計で測定して80dBになるように出力を調整して下さい";...
                "調整できたらチェックボックスにマークして下さい"];
            calibrateInput(app);
        end

        % Button pushed function: AdjustheadphoneButton
        function AdjustheadphoneButtonPushed(app, event)
            app.AdjustheadphoneButton.Enable = "off";
            app.AbortButton_2.Visible = "on";
            app.AbortButton_2.Enable = "on";
            app.Label_time_2.Enable = "on";
            app.Label_time_2.Visible = "on";
            app.TimesLabel_2.Enable = "on";
            app.TimesLabel_2.Visible = "on";
            app.dBremuPaLabel.Visible = "on";
            app.dBremuPaLabel.Enable = "on";
            app.LabelforDB_2.Enable = "on";
            app.LabelforDB_2.Visible = "on";
            app.TextArea.Value = ["ヘッドフォンのレベルを調整します。音は60秒続きます";...
                "表示が 80dB re. muPa になるように、ヘッドフォンの出力レベルを調整して下さい"; ...
                "調整できたら OK done のボタンを押して下さい"];
            fprintf(app.CommonData.logFid, 'Headphone adjust button pushed, %s\n', ...
                string(datetime));
            adjustLevelBody(app);
        end

        % Button pushed function: OKdoneButton
        function OKdoneButtonPushed(app, event)
            app.OKdoneButton.Enable = "off";
            app.CommonData.running = "off";
            app.Measure_latency.Enable = "on";
            app.Measure_latency.Visible = "on";
            app.TextArea.Value = ["次に、残響を正しく動作させるための確認です";...
                "Measure latency ボタンを押して下さい"; ...
                "6秒間ほど測定用の信号が出ます。"; ...
                "測定が終わると、ここに結果と説明を表示します。"; ...
                "必要に応じてAudio InterfaceやNoise settingの設定を変更して下さい。";...
                "[READY GO]ボタンで実験を始めることができます"];
            app.AdjustheadphoneButton.Enable = "on";
            fprintf(app.CommonData.logFid, 'Headphone adjusted, %s\n', ...
                string(datetime));
            app.Measure_latency.Enable = "on";
            app.Measure_latency.Visible = "on";
            app.READYGOButton.Enable = "on";
            app.READYGOButton.Visible = "on";
            app.NoisesettingPanel.Enable = "on";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fprintf(app.CommonData.logFid, 'Session closed, %s\n', ...
                string(datetime));
            fclose(app.CommonData.logFid);
            pause(1);
            delete(app)
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.CommonData.running = "off";
            app.CommonData.aborted = "yes";
            fprintf(app.CommonData.logFid, 'Calibration cancelled, %s\n', ...
                string(datetime));
            app.TextArea.Value = ["較正をやり直します";...
                "マイクロフォンの位置での音圧を騒音計でモニターします"; ...
                "A特性、SLOWで80dBになるように音量を調整して下さい"; ...
                "調整できたらチェックボックスにマークして下さい"];
        end

        % Button pushed function: AbortButton_2
        function AbortButton_2Pushed(app, event)
            app.CommonData.running = "off";
            app.CommonData.aborted = "yes";
            fprintf(app.CommonData.logFid, 'Headphone adjust cancelled, %s\n', ...
                string(datetime));
        end

        % Button pushed function: Measure_latency
        function Measure_latencyButtonPushed(app, event)
            fprintf(app.CommonData.logFid, 'Measure letency button pushed, %s\n', ...
                string(datetime));
            fs = app.CommonData.samplingFrequency;
            iResp = app.CommonData.revImpulse;
            iBuffLength = str2double(app.BuffersizesamplesDropDown.Value);
            devName = app.CommonData.ioDevice;
            output = genelicLatencyTester(iResp,iBuffLength,devName,fs);
            outName = append("latTest", ...
                string(datetime("now","Format","uuuuMMdd'T'HHmmss")),".mat");
            outFullpath = append(app.CommonData.resultDir,"/",outName);
            save(outFullpath,"output");
            app.TextArea.Value = ["マイク入力を処理してヘッドフォンから出すまでの時間は"; ...
                append(num2str(output.latencyMs), " ms です"); ...
                append("OverRunとUnderRunは ", ...
                num2str([output.totalOverRun, output.totalUnderRun])," 個です")];
            app.READYGOButton.Enable = "on";
            app.READYGOButton.Visible = "on";
        end

        % Button pushed function: READYGOButton
        function READYGOButtonPushed(app, event)
            fprintf(app.CommonData.logFid, 'READY GO button pushed, %s\n', ...
                string(datetime));
            app.ResponsepanelPanel.Visible = "on";
            app.ResponsepanelPanel.Enable = "on";
            app.AdjustheadphonelevelPanel.Visible = "off";
            app.CalibrationPanel.Visible = "off";
            app.ReverberationimpulseresponsePanel.Visible = "off";
            app.AudiointerfacePanel.Visible = "off";
            app.NoisesettingPanel.Visible = "off";
            app.TextArea.Value = ["実験を始めるための装置の調整が終わりました"; ...
                "ヘッドフォンをかけてマイクを録音用に設定して下さい";...
                "START Trial ボタンを押すと実験が始まって、自分の声が響いて聴こえます"; ...
                "STOP Trial ボタンを押すと響きが止まります";...
                "最初の回は、ボタンを押した後に自分の名前を言ってから、始めて下さい"];
        end

        % Button pushed function: FinishexperimentButton
        function FinishexperimentButtonPushed(app, event)
            app.FinishexperimentButton.Enable = "off";
            fprintf(app.CommonData.logFid, 'Finish button pushed, %s\n', ...
                string(datetime));
            app.TextArea.Value = ["これで実験は終わりました";...
                "ありがとうございました"];
        end

        % Button pushed function: Voicememo5sButton
        function Voicememo5sButtonPushed(app, event)
            fprintf(app.CommonData.logFid, 'Voicememo button pushed, %s\n', ...
                string(datetime));
            originalMessage = app.TextArea.Value;
            app.TextArea.Value = "緑のランプが点灯している5秒間、録音します";
            app.Voicememo5sButton.Enable = "off";
            app.VoiceMemoCountdown.Visible = "on";
            app.VoiceMemoCountdown.Enable = "on";
            fs = 44100;
            ioBufferSize = 2048;
            silence = zeros(ioBufferSize,1);
            playrec = audioPlayerRecorder(fs,"BitDepth",...
                "24-bit integer","BufferSize",ioBufferSize,...
                "Device",app.CommonData.ioDevice,"SupportVariableSize",true);
            for ii = 30:-1:0
                pause(0.1);
                app.VoiceMemoCountdown.Text = num2str(ii/10);
            end
            app.RecordingLamp.Visible = "on";
            app.RecordingLamp.Enable = "on";
            memoVoice = zeros(round(5*fs),1);
            count = 0;
            maxCount = floor(5*fs/ioBufferSize);
            while count < maxCount
                readData = playrec(silence);
                memoVoice(count*ioBufferSize+(1:ioBufferSize)) = readData;
                count = count + 1;
                app.VoiceMemoCountdown.Text = ...
                    num2str(5-count*ioBufferSize/fs,"%5.2f");
                drawnow
            end
            release(app.CommonData.playrec);
            outName = append("vMemo", ...
                string(datetime("now","Format","uuuuMMdd'T'HHmmss")),".wav");
            outFullpath = append(app.CommonData.resultDir,"/",outName);
            audiowrite(outFullpath,memoVoice,fs,"BitsPerSample",24);
            app.RecordingLamp.Visible = "off";
            app.RecordingLamp.Enable = "off";
            app.VoiceMemoCountdown.Visible = "off";
            app.VoiceMemoCountdown.Enable = "off";
            app.Voicememo5sButton.Enable = "on";
            app.TextArea.Value = originalMessage;
        end

        % Button pushed function: STOPTrialButton
        function STOPTrialButtonPushed(app, event)
            app.CommonData.running = "off";
            app.STOPTrialButton.Enable = "off";
            app.STARTTrialButton.Enable = "on";
            app.FinishexperimentButton.Enable = "on";
            app.FeedbackleveldBDropDown.Enable = "on";
            fprintf(app.CommonData.logFid, 'STOP Trial button pushed, %s\n', ...
                string(datetime));
        end

        % Button pushed function: STARTTrialButton
        function STARTTrialButtonPushed(app, event)
            app.STARTTrialButton.Enable = "off";
            app.STOPTrialButton.Enable = "on";
            app.FeedbackleveldBDropDown.Enable = "off";
            fprintf(app.CommonData.logFid, 'START Trial button pushed, %s\n', ...
                string(datetime));
            fprintf(app.CommonData.logFid, 'Feedback level setting, %s\n', ...
                app.FeedbackleveldBDropDown.Value);
            app.TextArea.Value = ["実験の試行中です";...
                "STOP Trial ボタンを押すと、この試行は終わります"];
            reverberationTestBody(app);
        end

        % Value changed function: SPL80dBadjustedCheckBox
        function SPL80dBadjustedCheckBoxValueChanged(app, event)
            value = app.SPL80dBadjustedCheckBox.Value;
            app.SPL80dBadjustedCheckBox.Enable = "off";
            fprintf(app.CommonData.logFid, 'SPL80dB checkbox changed, %s\n', ...
                string(datetime));
            if value == 1
                app.SensitivityOKButton.Enable = "on";
                app.TextArea.Value = ["音圧が80dBになりました";...
                "Sensitivity OK ボタンの上の数字を見ながら入力の感度を調整して下さい"; ...
                "-26dBが目標です。2dB程度違っても大丈夫です"; ...
                "できたら、Sensitivity OK ボタンをクリックして下さい"];
            else
                app.SensitivityOKButton.Enable = "off";
            end
        end

        % Button pushed function: SensitivityOKButton
        function SensitivityOKButtonPushed(app, event)
                app.CommonData.calibrationdB = ...
                    80 - app.CommonData.currntLevel;
                app.CommonData.running = "off";
                app.CalibrationPanel.Enable = "off";
                app.BuffersizesamplesDropDown.Enable = "on";
                delimiter = ", ";
                fprintf(app.CommonData.logFid, 'Calibration (dB), %s\n', ...
                    append(num2str(app.CommonData.calibrationdB), ...
                    delimiter, string(datetime)));
                adjustHeadphone(app);
        end

        % Value changed function: NoiseDropDown
        function NoiseDropDownValueChanged(app, event)
            value = app.NoiseDropDown.Value;
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'NoiseType, %s\n', ...
                    append(string(value), ...
                    delimiter, string(datetime)));
            switch value
                case app.NoiseDropDown.Items{2}
                    fetchNoiseFile(app);
                otherwise
                    generateNoise(app);
            end
        end

        % Value changed function: LevelAdBDropDown
        function LevelAdBDropDownValueChanged(app, event)
            value = app.LevelAdBDropDown.Value;
            if isnumeric(value)
                app.CommonData.noiseLevel = str2double(value);
            else
                app.CommonData.noiseLevel = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ResponsepanelPanel = uipanel(app.UIFigure);
            app.ResponsepanelPanel.Enable = 'off';
            app.ResponsepanelPanel.Title = 'Response panel';
            app.ResponsepanelPanel.Visible = 'off';
            app.STARTTrialButton = uibutton(app.ResponsepanelPanel, 'push');
            app.STARTTrialButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTrialButtonPushed, true);
            app.STARTTrialButton.Text = 'START Trial';
            app.STOPTrialButton = uibutton(app.ResponsepanelPanel, 'push');
            app.STOPTrialButton.ButtonPushedFcn = createCallbackFcn(app, @STOPTrialButtonPushed, true);
            app.STOPTrialButton.Enable = 'off';
            app.STOPTrialButton.Text = 'STOP Trial';
            app.FinishexperimentButton = uibutton(app.ResponsepanelPanel, 'push');
            app.FinishexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @FinishexperimentButtonPushed, true);
            app.FinishexperimentButton.Enable = 'off';
            app.FinishexperimentButton.Text = 'Finish experiment';
            app.ExperimentsettingPanel = uipanel(app.ResponsepanelPanel);
            app.ExperimentsettingPanel.Title = 'Experiment setting';
            app.FeedbackleveldBDropDownLabel = uilabel(app.ExperimentsettingPanel);
            app.FeedbackleveldBDropDownLabel.Text = 'Feedback level (dB)';
            app.FeedbackleveldBDropDown = uidropdown(app.ExperimentsettingPanel);
            app.FeedbackleveldBDropDown.Items = {'0', '-5', '-10', '-15', '-20', '-25', '-30', '-35'};
            app.FeedbackleveldBDropDown.Value = '0';
            app.Label_2 = uilabel(app.ResponsepanelPanel);
            app.Label_2.Text = '0';
            app.ReverberationwithLombardspeechtesterLabel = uilabel(app.UIFigure);
            app.ReverberationwithLombardspeechtesterLabel.Text = 'Reverberation with Lombard speech tester';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '説明';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.AudiointerfacePanel = uipanel(app.UIFigure);
            app.AudiointerfacePanel.Title = 'Audio interface';
            app.DeviceDropDownLabel = uilabel(app.AudiointerfacePanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.AudiointerfacePanel);
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.BuffersizesamplesDropDownLabel = uilabel(app.AudiointerfacePanel);
            app.BuffersizesamplesDropDownLabel.Text = 'Buffer size (samples)';
            app.BuffersizesamplesDropDown = uidropdown(app.AudiointerfacePanel);
            app.BuffersizesamplesDropDown.Items = {'1024', '512', '256', '128', '64'};
            app.BuffersizesamplesDropDown.Enable = 'off';
            app.BuffersizesamplesDropDown.Value = '512';
            app.SamplingfrequensyHzTextAreaLabel = uilabel(app.AudiointerfacePanel);
            app.SamplingfrequensyHzTextAreaLabel.Text = 'Sampling frequensy (Hz)';
            app.SamplingfrequensyHzTextArea = uitextarea(app.AudiointerfacePanel);
            app.SamplingfrequensyHzTextArea.Editable = 'off';
            app.SamplingfrequensyHzTextArea.Value = {'44100'};
            app.READYGOButton = uibutton(app.AudiointerfacePanel, 'push');
            app.READYGOButton.ButtonPushedFcn = createCallbackFcn(app, @READYGOButtonPushed, true);
            app.READYGOButton.Enable = 'off';
            app.READYGOButton.Visible = 'off';
            app.READYGOButton.Text = 'READY, GO';
            app.ReverberationimpulseresponsePanel = uipanel(app.UIFigure);
            app.ReverberationimpulseresponsePanel.Enable = 'off';
            app.ReverberationimpulseresponsePanel.Title = 'Reverberation impulse response';
            app.UIAxes = uiaxes(app.ReverberationimpulseresponsePanel);
            zlabel(app.UIAxes, 'Z')
            app.FolderTextAreaLabel = uilabel(app.ReverberationimpulseresponsePanel);
            app.FolderTextAreaLabel.Text = 'Folder';
            app.FolderTextArea = uitextarea(app.ReverberationimpulseresponsePanel);
            app.FolderTextArea.Editable = 'off';
            app.FileDropDownLabel = uilabel(app.ReverberationimpulseresponsePanel);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.ReverberationimpulseresponsePanel);
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.MagSliderLabel = uilabel(app.ReverberationimpulseresponsePanel);
            app.MagSliderLabel.Text = 'Mag';
            app.MagSlider = uislider(app.ReverberationimpulseresponsePanel);
            app.MagSlider.Limits = [0 8];
            app.MagSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.MagSlider.MajorTickLabels = {'1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.MagSlider.ValueChangingFcn = createCallbackFcn(app, @MagSliderValueChanging, true);
            app.MagSlider.MinorTicks = [];
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Enable = 'off';
            app.CalibrationPanel.Title = 'Calibration';
            app.LabelforDB = uilabel(app.CalibrationPanel);
            app.LabelforDB.Enable = 'off';
            app.LabelforDB.Visible = 'off';
            app.LabelforDB.Text = '-100';
            app.dBreMSBLabel = uilabel(app.CalibrationPanel);
            app.dBreMSBLabel.Enable = 'off';
            app.dBreMSBLabel.Visible = 'off';
            app.dBreMSBLabel.Text = 'dB re. MSB';
            app.CalibrationButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Enable = 'off';
            app.CalibrationButton.Text = 'Calibration';
            app.TimesLabel = uilabel(app.CalibrationPanel);
            app.TimesLabel.Enable = 'off';
            app.TimesLabel.Visible = 'off';
            app.TimesLabel.Text = 'Time (s)';
            app.Label_time = uilabel(app.CalibrationPanel);
            app.Label_time.Enable = 'off';
            app.Label_time.Visible = 'off';
            app.Label_time.Text = '0';
            app.AbortButton = uibutton(app.CalibrationPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Visible = 'off';
            app.AbortButton.Text = 'Abort';
            app.SensitivityOKButton = uibutton(app.CalibrationPanel, 'push');
            app.SensitivityOKButton.ButtonPushedFcn = createCallbackFcn(app, @SensitivityOKButtonPushed, true);
            app.SensitivityOKButton.Enable = 'off';
            app.SensitivityOKButton.Text = 'Sensitivity OK';
            app.SPL80dBadjustedCheckBox = uicheckbox(app.CalibrationPanel);
            app.SPL80dBadjustedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SPL80dBadjustedCheckBoxValueChanged, true);
            app.SPL80dBadjustedCheckBox.Enable = 'off';
            app.SPL80dBadjustedCheckBox.Text = 'SPL 80dB adjusted';
            app.Voicememo5sButton = uibutton(app.UIFigure, 'push');
            app.Voicememo5sButton.ButtonPushedFcn = createCallbackFcn(app, @Voicememo5sButtonPushed, true);
            app.Voicememo5sButton.Visible = 'off';
            app.Voicememo5sButton.Text = 'Voice memo 5s';
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Visible = 'off';
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.UIFigure);
            app.RecordingLamp.Visible = 'off';
            app.VoiceMemoCountdown = uilabel(app.UIFigure);
            app.VoiceMemoCountdown.Visible = 'off';
            app.VoiceMemoCountdown.Text = '0';
            app.AdjustheadphonelevelPanel = uipanel(app.UIFigure);
            app.AdjustheadphonelevelPanel.Enable = 'off';
            app.AdjustheadphonelevelPanel.Title = 'Adjust headphone level';
            app.LabelforDB_2 = uilabel(app.AdjustheadphonelevelPanel);
            app.LabelforDB_2.Enable = 'off';
            app.LabelforDB_2.Visible = 'off';
            app.LabelforDB_2.Text = '-100';
            app.dBremuPaLabel = uilabel(app.AdjustheadphonelevelPanel);
            app.dBremuPaLabel.Enable = 'off';
            app.dBremuPaLabel.Visible = 'off';
            app.dBremuPaLabel.Text = 'dB re. muPa';
            app.AdjustheadphoneButton = uibutton(app.AdjustheadphonelevelPanel, 'push');
            app.AdjustheadphoneButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustheadphoneButtonPushed, true);
            app.AdjustheadphoneButton.Text = 'Adjust headphone';
            app.OKdoneButton = uibutton(app.AdjustheadphonelevelPanel, 'push');
            app.OKdoneButton.ButtonPushedFcn = createCallbackFcn(app, @OKdoneButtonPushed, true);
            app.OKdoneButton.Enable = 'off';
            app.OKdoneButton.Text = 'OK done';
            app.TimesLabel_2 = uilabel(app.AdjustheadphonelevelPanel);
            app.TimesLabel_2.Enable = 'off';
            app.TimesLabel_2.Visible = 'off';
            app.TimesLabel_2.Text = 'Time (s)';
            app.Label_time_2 = uilabel(app.AdjustheadphonelevelPanel);
            app.Label_time_2.Enable = 'off';
            app.Label_time_2.Visible = 'off';
            app.Label_time_2.Text = '0';
            app.Measure_latency = uibutton(app.AdjustheadphonelevelPanel, 'push');
            app.Measure_latency.ButtonPushedFcn = createCallbackFcn(app, @Measure_latencyButtonPushed, true);
            app.Measure_latency.Enable = 'off';
            app.Measure_latency.Visible = 'off';
            app.Measure_latency.Text = 'Measure latency';
            app.AbortButton_2 = uibutton(app.AdjustheadphonelevelPanel, 'push');
            app.AbortButton_2.ButtonPushedFcn = createCallbackFcn(app, @AbortButton_2Pushed, true);
            app.AbortButton_2.Enable = 'off';
            app.AbortButton_2.Visible = 'off';
            app.AbortButton_2.Text = 'Abort';
            app.NoisesettingPanel = uipanel(app.UIFigure);
            app.NoisesettingPanel.Enable = 'off';
            app.NoisesettingPanel.Title = 'Noise setting';
            app.LevelAdBDropDownLabel = uilabel(app.NoisesettingPanel);
            app.LevelAdBDropDownLabel.Text = 'Level  A-dB';
            app.LevelAdBDropDown = uidropdown(app.NoisesettingPanel);
            app.LevelAdBDropDown.Items = {'----', '90', '85', '80', '75', '70', '65', '60'};
            app.LevelAdBDropDown.ValueChangedFcn = createCallbackFcn(app, @LevelAdBDropDownValueChanged, true);
            app.LevelAdBDropDown.Value = '----';
            app.NoiseDropDownLabel = uilabel(app.NoisesettingPanel);
            app.NoiseDropDownLabel.Text = 'Noise';
            app.NoiseDropDown = uidropdown(app.NoisesettingPanel);
            app.NoiseDropDown.Items = {'None', 'FromFile', 'Pink', 'White', 'Hoth', 'Babble'};
            app.NoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseDropDownValueChanged, true);
            app.NoiseDropDown.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reverbeLmbrdSpeechTesterRev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end